classdef app_fis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CondicinEditField       matlab.ui.control.EditField
        CondicinEditFieldLabel  matlab.ui.control.Label
        EstadoLamp              matlab.ui.control.Lamp
        EstadoLampLabel         matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
        data
        x_temp
        y_temp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all

            % port = serialportlist("available");
            app.s = serialport("COM3",9600);
            flush(app.s)

            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Temperatura (°C)')
            title(app.UIAxes, 'Medición Temperatura')
            
            app.y_temp = [];
            ylim(app.UIAxes, [0, 100])
            while true
                app.y_temp = [app.y_temp str2double(readline(app.s))];
                plot(app.UIAxes, app.y_temp, 'ro-')
                txt = ['Temperatura actual: ' num2str(app.y_temp(end)) ' °C'];
                text(app.UIAxes, length(app.y_temp), app.y_temp(end)+5,txt,'HorizontalAlignment','right')
                if app.y_temp(end) > 38
                    app.EstadoLamp.Color = 'red';
                    app.CondicinEditField.Value = sprintf('Usted está en estado de Fiebre.');
                elseif app.y_temp(end) < 36
                    app.EstadoLamp.Color = 'blue';
                    app.CondicinEditField.Value = sprintf('Usted está en estado de Hipotermia.');
                else
                    app.EstadoLamp.Color = 'green';
                    app.CondicinEditField.Value = sprintf('Su temperatura es normal');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EstadoLampLabel = uilabel(app.UIFigure);
            app.EstadoLampLabel.Text = 'Estado';
            app.EstadoLamp = uilamp(app.UIFigure);
            app.CondicinEditFieldLabel = uilabel(app.UIFigure);
            app.CondicinEditFieldLabel.Text = 'Condición';
            app.CondicinEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_fis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end